classdef brazos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AplicarButton         matlab.ui.control.Button
        Brazo2EditField       matlab.ui.control.NumericEditField
        Brazo2EditFieldLabel  matlab.ui.control.Label
        Brazo1EditField       matlab.ui.control.NumericEditField
        Brazo1EditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        b1 = [0.3,0.5;
              0.5,0.5];
        b2 = [0.5,0.7;
              0.5,0.5];
        vect1;
        vect2;
    end
    
    methods (Access = private)
        
        function newV = transformer(~, vector, angle)
            puntos = [vector;ones(1,length(vector))];
            rad = angle*(pi/180);
            t(1) = -1*(puntos(1,1));
            t(2) = -1*(puntos(2,1));
            tp(1) = (puntos(1,1));
            tp(2) = (puntos(2,1));

            mt = [cos(rad),sin(rad),(t(1)*cos(rad)+t(2)*sin(rad)+tp(1));
                 -sin(rad),cos(rad),(-t(1)*sin(rad)+t(2)*cos(rad)+tp(2));     
                  0,0,1];

            newV = mt*puntos;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, "on");
            app.vect1 = plot(app.UIAxes, app.b1(1,:), app.b1(2,:), 'k');
            app.vect2 = plot(app.UIAxes, app.b2(1,:), app.b2(2,:), 'r');
            hold(app.UIAxes, "off");
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            value = app.Brazo1EditField.Value;
            if (value ~= 0)
                x1 = get(app.vect1, 'XData');
                y1 = get(app.vect1, 'YData');
                x2 = get(app.vect2, 'XData');
                y2 = get(app.vect2, 'YData');
                v = [x1, x2;y1, y2];
                newv = transformer(app, v, value);
                set(app.vect1, 'XData', newv(1,1:2), 'YData', newv(2,1:2));
                set(app.vect2, 'XData', newv(1,3:4), 'YData', newv(2,3:4));
            end

            value = app.Brazo2EditField.Value;
            if (value ~= 0)
                x2 = get(app.vect2, 'XData');
                y2 = get(app.vect2, 'YData');
                v = [x2;y2];
                newv = transformer(app, v, value);
                set(app.vect2, 'XData', newv(1,1:2), 'YData', newv(2,1:2));
            end
            app.Brazo1EditField.Value = 0;
            app.Brazo2EditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.Brazo1EditFieldLabel = uilabel(app.UIFigure);
            app.Brazo1EditFieldLabel.Text = 'Brazo 1';
            app.Brazo1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Brazo1EditField.Limits = [-360 360];
            app.Brazo2EditFieldLabel = uilabel(app.UIFigure);
            app.Brazo2EditFieldLabel.Text = 'Brazo 2';
            app.Brazo2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Brazo2EditField.Limits = [-360 360];
            app.AplicarButton = uibutton(app.UIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brazos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end