classdef reloj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vs = [0.5, 0.5;
              0.5, 1.0];
        vm = [0.5, 0.5;
              0.5, 0.9];
        vh = [0.5, 0.5;
              0.5, 0.8];
        theta = (2*pi)/60;
        vectors;
        vectorm;
        vectorh;
        tim;
    end
    
    methods (Access = private)
        
        function newVector = transformer(~, vector, angle)
            tx = -1*vector(1,1);
            ty = -1*vector(2,1);
            tpx = vector(1,1);
            tpy = vector(2,1);
            puntos = [vector;
                      ones(1,length(vector))];
            mt = [cos(angle), sin(angle), tx*cos(angle)+ty*sin(angle)+tpx;
                 -sin(angle), cos(angle), -tx*sin(angle)+ty*cos(angle)+tpy;
                  0,0,1];
            newVector = mt * puntos;
        end
        
        function grafica(app)
            x = get(app.vectors, 'XData');
            y = get(app.vectors, 'YData');
            p = [x;y];
            newP = transformer(app, p, app.theta);
            set(app.vectors, 'XData', newP(1,:));
            set(app.vectors, 'YData', newP(2,:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [h,m,s] = hms(datetime("now"));
            t = app.theta * floor(s);
            t2 = app.theta * m;
            t3 = ((2*pi)/12)*h;
            v = transformer(app, app.vs, t);
            vm = transformer(app, app.vm, t2);
            vh = transformer(app, app.vh, t3);

            hold(app.UIAxes,"on");
            app.vectors = plot(app.UIAxes, v(1,:), v(2,:), 'k');
            app.vectorm = plot(app.UIAxes, vm(1,:), vm(2,:), 'k', LineWidth=2);
            app.vectorh = plot(app.UIAxes, vh(1,:), vh(2,:), 'k', LineWidth=3);
            plot(app.UIAxes, 0.5, 0.5,'o', MarkerSize=8, MarkerFaceColor="k");
            hold(app.UIAxes, "off");
            app.tim = timer("ExecutionMode","fixedSpacing","Period",1, ...
                "TasksToExecute",inf,"TimerFcn",@(~,~)grafica(app));
            start(app.tim);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reloj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end