classdef graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficadoraUIFigure    matlab.ui.Figure
        TangenteSwitch         matlab.ui.control.ToggleSwitch
        TangenteSwitchLabel    matlab.ui.control.Label
        CosenoSwitch           matlab.ui.control.ToggleSwitch
        CosenoSwitchLabel      matlab.ui.control.Label
        SenoSwitch             matlab.ui.control.ToggleSwitch
        SenoSwitchLabel        matlab.ui.control.Label
        FaseSlider             matlab.ui.control.Slider
        FaseSliderLabel        matlab.ui.control.Label
        FrecuenciaSlider       matlab.ui.control.Slider
        FrecuenciaSliderLabel  matlab.ui.control.Label
        AmplitudSlider         matlab.ui.control.Slider
        AmplitudSliderLabel    matlab.ui.control.Label
        Grafica                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X=linspace(-2*pi,2*pi,1000);
        A = 1;
        B = 1;
        C = 0;
        SenoOn = true;
    end
    
    methods (Access = private)
        
        function [] = ploteaSeno(app)
            if app.SenoOn
                Y=app.A * sin(app.B * app.X + app.C);
                plot(app.Grafica, app.X, Y, "r", "LineWidth", 2);
            else
                cla(app.Grafica);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ploteaSeno(app);
        end

        % Value changing function: AmplitudSlider
        function AmplitudSliderValueChanging(app, event)
            app.A = event.Value;
            ploteaSeno(app);
        end

        % Value changing function: FrecuenciaSlider
        function FrecuenciaSliderValueChanging(app, event)
            app.B = event.Value;
            ploteaSeno(app);
        end

        % Value changing function: FaseSlider
        function FaseSliderValueChanging(app, event)
            app.C = event.Value;
            ploteaSeno(app);
        end

        % Value changed function: SenoSwitch
        function SenoSwitchValueChanged(app, event)
            value = app.SenoSwitch.Value;
            if value == "On"
                app.SenoOn = true;
            else
                app.SenoOn = false;
            end
            ploteaSeno(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficadoraUIFigure = uifigure('Visible', 'off');
            app.GraficadoraUIFigure.Name = 'Graficadora';
            app.Grafica = uiaxes(app.GraficadoraUIFigure);
            title(app.Grafica, 'Grafica')
            zlabel(app.Grafica, 'Z')
            app.Grafica.XLim = [-6.28318530717959 6.28318530717959];
            app.Grafica.YLim = [-10 10];
            app.Grafica.XTick = [-6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6];
            app.Grafica.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.AmplitudSliderLabel = uilabel(app.GraficadoraUIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.GraficadoraUIFigure);
            app.AmplitudSlider.Limits = [0 10];
            app.AmplitudSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudSliderValueChanging, true);
            app.AmplitudSlider.Value = 1;
            app.FrecuenciaSliderLabel = uilabel(app.GraficadoraUIFigure);
            app.FrecuenciaSliderLabel.Text = 'Frecuencia';
            app.FrecuenciaSlider = uislider(app.GraficadoraUIFigure);
            app.FrecuenciaSlider.Limits = [0 6.28318530717959];
            app.FrecuenciaSlider.ValueChangingFcn = createCallbackFcn(app, @FrecuenciaSliderValueChanging, true);
            app.FrecuenciaSlider.Value = 1;
            app.FaseSliderLabel = uilabel(app.GraficadoraUIFigure);
            app.FaseSliderLabel.Text = 'Fase';
            app.FaseSlider = uislider(app.GraficadoraUIFigure);
            app.FaseSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.FaseSlider.ValueChangingFcn = createCallbackFcn(app, @FaseSliderValueChanging, true);
            app.SenoSwitchLabel = uilabel(app.GraficadoraUIFigure);
            app.SenoSwitchLabel.Text = 'Seno';
            app.SenoSwitch = uiswitch(app.GraficadoraUIFigure, 'toggle');
            app.SenoSwitch.ValueChangedFcn = createCallbackFcn(app, @SenoSwitchValueChanged, true);
            app.SenoSwitch.Value = 'On';
            app.CosenoSwitchLabel = uilabel(app.GraficadoraUIFigure);
            app.CosenoSwitchLabel.Text = 'Coseno';
            app.CosenoSwitch = uiswitch(app.GraficadoraUIFigure, 'toggle');
            app.CosenoSwitch.Value = 'On';
            app.TangenteSwitchLabel = uilabel(app.GraficadoraUIFigure);
            app.TangenteSwitchLabel.Text = 'Tangente';
            app.TangenteSwitch = uiswitch(app.GraficadoraUIFigure, 'toggle');
            app.TangenteSwitch.Value = 'On';
            % Show the figure after all components are created
            app.GraficadoraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficadoraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficadoraUIFigure)
        end
    end
end