classdef graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiGraficadoraUIFigure  matlab.ui.Figure
        TangenteSwitch         matlab.ui.control.ToggleSwitch
        TangenteSwitchLabel    matlab.ui.control.Label
        CosenoSwitch           matlab.ui.control.ToggleSwitch
        CosenoSwitchLabel      matlab.ui.control.Label
        SenoSwitch             matlab.ui.control.ToggleSwitch
        SenoSwitchLabel        matlab.ui.control.Label
        FaseSlider             matlab.ui.control.Slider
        FaseSliderLabel        matlab.ui.control.Label
        FrecuenciaSlider       matlab.ui.control.Slider
        FrecuenciaSliderLabel  matlab.ui.control.Label
        AmplitudSlider         matlab.ui.control.Slider
        AmplitudSliderLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = linspace(-2*pi,2*pi,1000);
        A = 1;
        B = 1;
        C = 0;
        SenoOn = true;
        CosenoOn = false;
    end
    
    methods (Access = private)
        
        function [] = seno(app)
            if app.SenoOn
                plot(app.UIAxes, app.x, app.A*sin(app.B*app.x + app.C), "r");
            end
        end
        
        function [] = coseno(app)
            if app.CosenoOn
                plot(app.UIAxes, app.x, app.A*cos(app.B*app.x + app.C), "b");
            end
        end
        
        function [] = grafica(app)
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            seno(app);
            coseno(app);
            hold(app.UIAxes, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            grafica(app);
        end

        % Value changed function: AmplitudSlider
        function AmplitudSliderValueChanged(app, event)
            app.A = app.AmplitudSlider.Value;
            grafica(app);
        end

        % Value changed function: FrecuenciaSlider
        function FrecuenciaSliderValueChanged(app, event)
            app.B = app.FrecuenciaSlider.Value;
            grafica(app);
        end

        % Value changed function: FaseSlider
        function FaseSliderValueChanged(app, event)
            app.C = app.FaseSlider.Value;
            grafica(app);
        end

        % Value changing function: FaseSlider
        function FaseSliderValueChanging(app, event)
            app.C = event.Value;
            grafica(app);
        end

        % Value changed function: SenoSwitch
        function SenoSwitchValueChanged(app, event)
            value = app.SenoSwitch.Value;
            if value == "On"
                app.SenoOn = true;
            else
                app.SenoOn = false;
            end
            grafica(app);
        end

        % Value changed function: CosenoSwitch
        function CosenoSwitchValueChanged(app, event)
            value = app.CosenoSwitch.Value;
            if value == "On"
                app.CosenoOn = true;
            else
                app.CosenoOn = false;
            end
            grafica(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiGraficadoraUIFigure = uifigure('Visible', 'off');
            app.MiGraficadoraUIFigure.Name = 'Mi Graficadora';
            app.UIAxes = uiaxes(app.MiGraficadoraUIFigure);
            title(app.UIAxes, 'Graficas')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-6.28318530717959 6.28318530717959];
            app.UIAxes.YLim = [-10 10];
            app.AmplitudSliderLabel = uilabel(app.MiGraficadoraUIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.MiGraficadoraUIFigure);
            app.AmplitudSlider.Limits = [-10 10];
            app.AmplitudSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.AmplitudSlider.Value = 1;
            app.FrecuenciaSliderLabel = uilabel(app.MiGraficadoraUIFigure);
            app.FrecuenciaSliderLabel.Text = 'Frecuencia';
            app.FrecuenciaSlider = uislider(app.MiGraficadoraUIFigure);
            app.FrecuenciaSlider.Limits = [-7 7];
            app.FrecuenciaSlider.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaSliderValueChanged, true);
            app.FrecuenciaSlider.Value = 1;
            app.FaseSliderLabel = uilabel(app.MiGraficadoraUIFigure);
            app.FaseSliderLabel.Text = 'Fase';
            app.FaseSlider = uislider(app.MiGraficadoraUIFigure);
            app.FaseSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.FaseSlider.ValueChangedFcn = createCallbackFcn(app, @FaseSliderValueChanged, true);
            app.FaseSlider.ValueChangingFcn = createCallbackFcn(app, @FaseSliderValueChanging, true);
            app.SenoSwitchLabel = uilabel(app.MiGraficadoraUIFigure);
            app.SenoSwitchLabel.Text = 'Seno';
            app.SenoSwitch = uiswitch(app.MiGraficadoraUIFigure, 'toggle');
            app.SenoSwitch.ValueChangedFcn = createCallbackFcn(app, @SenoSwitchValueChanged, true);
            app.SenoSwitch.Value = 'On';
            app.CosenoSwitchLabel = uilabel(app.MiGraficadoraUIFigure);
            app.CosenoSwitchLabel.Text = 'Coseno';
            app.CosenoSwitch = uiswitch(app.MiGraficadoraUIFigure, 'toggle');
            app.CosenoSwitch.ValueChangedFcn = createCallbackFcn(app, @CosenoSwitchValueChanged, true);
            app.TangenteSwitchLabel = uilabel(app.MiGraficadoraUIFigure);
            app.TangenteSwitchLabel.Text = 'Tangente';
            app.TangenteSwitch = uiswitch(app.MiGraficadoraUIFigure, 'toggle');
            % Show the figure after all components are created
            app.MiGraficadoraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiGraficadoraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiGraficadoraUIFigure)
        end
    end
end