classdef reloj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vector=[0 0;-0.2 0.9];
        vectorm=[0 0;0 0.8];
        vectorh=[0 0;0 0.6];
        theta = (2*pi)/60;
        theta2 = (2*pi)/12;
        segundero;
        minutero;
        horero;
        R;
        RH;
        tim;
    end
    
    methods (Access = private)
        
        function transforma(app)
            app.vector = app.R*app.vector;
            set(app.segundero, 'XData', app.vector(1,:), 'YData', ...
                app.vector(2,:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [h,m,s] = hms(datetime('now'));
            s = floor(s);
            tt = ((2*pi)/60)*s;
            rt = [cos(tt) sin(tt);-sin(tt) cos(tt)];
            app.vector = rt*app.vector;
            tt = ((2*pi)/60)*m;
            rt = [cos(tt) sin(tt);-sin(tt) cos(tt)];
            app.vectorm = rt*app.vectorm;
            tt = ((2*pi)/12)*h;
            rt = [cos(tt) sin(tt);-sin(tt) cos(tt)];
            app.vectorh = rt*app.vectorh;

            app.R = [ cos(app.theta), sin(app.theta);
                     -sin(app.theta), cos(app.theta)];

            
            hold(app.UIAxes,"on");
            app.segundero = plot(app.UIAxes, app.vector(1,:), app.vector(2,:), 'k');
            app.minutero = plot(app.UIAxes, app.vectorm(1,:), app.vectorm(2,:), 'k', 'LineWidth', 3);
            app.horero = plot(app.UIAxes, app.vectorh(1,:), app.vectorh(2,:), 'k', 'LineWidth',4);
            plot(app.UIAxes, 0,0,'ok', 'MarkerSize', 10, 'MarkerFaceColor','k');

            %Dibujamos la cara
            v = [0 0;0.95 1];
            for k=0:60
                if mod(k,5) == 0
                    if mod(k,15) == 0
                        plot(app.UIAxes, v(1,:), v(2,:), "Color",'k','LineWidth',8);
                    else
                        plot(app.UIAxes, v(1,:), v(2,:), "Color",'k', 'LineWidth',4);
                    end
                else
                    plot(app.UIAxes, v(1,:), v(2,:), "Color",'k');
                end
                
                v = app.R * v;
            end

            hold(app.UIAxes,"off");
            

            %timer
            app.tim = timer("ExecutionMode","fixedRate", "Period", 1, ...
                "TasksToExecute", inf,"TimerFcn", @(~,~)transforma(app));
            start(app.tim);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reloj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end